classdef Desalineaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_2                      matlab.ui.container.Panel
        Desalineacin1DropDownLabel   matlab.ui.control.Label
        Desalineacin1DropDown        matlab.ui.control.DropDown
        NextButton_2                 matlab.ui.control.Button
        Desalineacin2DropDownLabel   matlab.ui.control.Label
        Desalineacin2DropDown        matlab.ui.control.DropDown
        Desalineacin3DropDownLabel   matlab.ui.control.Label
        Desalineacin3DropDown        matlab.ui.control.DropDown
        Desalineacin5DropDownLabel   matlab.ui.control.Label
        Desalineacin5DropDown        matlab.ui.control.DropDown
        Desalineacin4DropDownLabel   matlab.ui.control.Label
        Desalineacin4DropDown        matlab.ui.control.DropDown
        Panel_3                      matlab.ui.container.Panel
        D1_2                         matlab.ui.control.NumericEditField
        D1_3                         matlab.ui.control.NumericEditField
        D1_4                         matlab.ui.control.NumericEditField
        D1_1                         matlab.ui.control.NumericEditField
        DL1                          matlab.ui.control.Label
        D2_2                         matlab.ui.control.NumericEditField
        D2_3                         matlab.ui.control.NumericEditField
        D2_4                         matlab.ui.control.NumericEditField
        D2_1                         matlab.ui.control.NumericEditField
        DL2                          matlab.ui.control.Label
        D3_2                         matlab.ui.control.NumericEditField
        D3_3                         matlab.ui.control.NumericEditField
        D3_4                         matlab.ui.control.NumericEditField
        D3_1                         matlab.ui.control.NumericEditField
        DL3                          matlab.ui.control.Label
        D4_2                         matlab.ui.control.NumericEditField
        D4_3                         matlab.ui.control.NumericEditField
        D4_4                         matlab.ui.control.NumericEditField
        D4_1                         matlab.ui.control.NumericEditField
        DL4                          matlab.ui.control.Label
        D5_2                         matlab.ui.control.NumericEditField
        D5_3                         matlab.ui.control.NumericEditField
        D5_4                         matlab.ui.control.NumericEditField
        D5_1                         matlab.ui.control.NumericEditField
        DL5                          matlab.ui.control.Label
        NextButton_3                 matlab.ui.control.Button
        LabelD1                      matlab.ui.control.Label
        LabelD1_2                    matlab.ui.control.Label
        LabelD1_3                    matlab.ui.control.Label
        LabelD1_4                    matlab.ui.control.Label
        LabelD2                      matlab.ui.control.Label
        LabelD2_2                    matlab.ui.control.Label
        LabelD2_3                    matlab.ui.control.Label
        LabelD2_4                    matlab.ui.control.Label
        LabelD3                      matlab.ui.control.Label
        LabelD4                      matlab.ui.control.Label
        LabelD5                      matlab.ui.control.Label
        LabelD3_2                    matlab.ui.control.Label
        LabelD4_2                    matlab.ui.control.Label
        LabelD5_2                    matlab.ui.control.Label
        LabelD3_3                    matlab.ui.control.Label
        LabelD4_3                    matlab.ui.control.Label
        LabelD5_3                    matlab.ui.control.Label
        LabelD3_4                    matlab.ui.control.Label
        LabelD4_4                    matlab.ui.control.Label
        LabelD5_4                    matlab.ui.control.Label
        Anuncio                      matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        ResultadosLabel              matlab.ui.control.Label
        R1                           matlab.ui.control.Label
        R2                           matlab.ui.control.Label
        R3                           matlab.ui.control.Label
        R4                           matlab.ui.control.Label
        R5                           matlab.ui.control.Label
        PerdidasPorL                 matlab.ui.control.Label
        LT                           matlab.ui.control.Label
        PS                           matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        QueHacePrograma              matlab.ui.control.Label
        Creditos                     matlab.ui.control.Label
        QueHacePrograma_2            matlab.ui.control.Label
        Ingrese                      matlab.ui.control.Label
        PotenciadeentradaLabel       matlab.ui.control.Label
        Pe                           matlab.ui.control.NumericEditField
        LongituddelafibraKmEditFieldLabel  matlab.ui.control.Label
        L                            matlab.ui.control.NumericEditField
        CoeficientedeatenuacindBKmEditFieldLabel  matlab.ui.control.Label
        Ate                          matlab.ui.control.NumericEditField
        NmerodedesalineacionesLabel  matlab.ui.control.Label
        N                            matlab.ui.control.NumericEditField
        NextButton                   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function LT = PerdidasL(~,alpha, L) 
            LT = -alpha*L;
        end
        
        function LT = Lateral(~,N1, n, y, a1) 
            L1 = ((16*(N1/n)^2)/(1+(N1/n))^4)*(1/pi)*((2*acos(y/(2*a1)))-((y/a1)*sqrt(1-(y/(2*a1))^2)));
            L2 = ((16*(n/N1)^2)/(1+(n/N1))^4)*(1/pi)*((2*acos(y/(2*a1)))-((y/a1)*sqrt(1-(y/(2*a1))^2)));
            LT = -10*log10(L1+L2);
        end
        
        function LT = Angular(~,N1, n, NA1, no)
            delta = ((NA1/N1)^2)/2;
            no = (no*pi)/180;
            L1 = ((16*(N1/n)^2)/(1+(N1/n))^4)*(1-(no/(pi*N1*sqrt(2*delta))));
            LT = 10*log10(L1);
        end
        
        function LT = Longitudinal(~,NA1, n0, s, a1)
            LT = 10*log10(1-((s*NA1)/(4*a1*n0)));
        end
        
        function LT = Diametros(~,a1, a2)
            if a1>a2 
                LT = 10*log10((a2/a1)^2);
            else
                LT = 10*log10((a1/a2)^2);
            end
        end
        
        function LT = Aperturas(~,NA1, NA2)
            if NA1>NA2 
                LT = 10*log10((NA2/NA1)^2);
            else
                LT = 10*log10((NA1/NA2)^2);
            end
        end
        
        function LT = PerdidasT(~,V, i)
            LT=0;
            i = i+1;
            while(i>0)
                LT = LT + V(i);
                i = i-1;
            end
        end
        
        function Ps = PotenciaSalida(~,Pe,L)
            Dbpe = 10*log10(Pe);
            Dbps = Dbpe + L;
            Ps = 10^(Dbps/10);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Panel_2.Visible = "off";
            app.Panel_3.Visible = "off";
            app.Panel_4.Visible = "off";
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.Panel.Visible = "off";
            app.Panel_2.Visible = "on";
            if app.N.Value == 1
                app.Desalineacin2DropDown.Visible = "off";
                app.Desalineacin3DropDown.Visible = "off";
                app.Desalineacin4DropDown.Visible = "off";
                app.Desalineacin5DropDown.Visible = "off";
                app.Desalineacin2DropDownLabel.Visible = "off";
                app.Desalineacin3DropDownLabel.Visible = "off";
                app.Desalineacin4DropDownLabel.Visible = "off";
                app.Desalineacin5DropDownLabel.Visible = "off";
            elseif app.N.Value == 2
                app.Desalineacin3DropDown.Visible = "off";
                app.Desalineacin4DropDown.Visible = "off";
                app.Desalineacin5DropDown.Visible = "off";
                app.Desalineacin3DropDownLabel.Visible = "off";
                app.Desalineacin4DropDownLabel.Visible = "off";
                app.Desalineacin5DropDownLabel.Visible = "off";
            elseif app.N.Value == 3
                app.Desalineacin4DropDown.Visible = "off";
                app.Desalineacin5DropDown.Visible = "off";
                app.Desalineacin4DropDownLabel.Visible = "off";
                app.Desalineacin5DropDownLabel.Visible = "off";
            elseif app.N.Value == 4
                app.Desalineacin5DropDown.Visible = "off";
                app.Desalineacin5DropDownLabel.Visible = "off";
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.Panel_2.Visible = "off";
            app.Panel_3.Visible = "on";
            if app.N.Value == 1
                app.DL2.Visible = "off";
                app.LabelD2.Visible = "off";
                app.LabelD2_2.Visible = "off";
                app.LabelD2_3.Visible = "off";
                app.LabelD2_4.Visible = "off";
                app.D2_1.Visible = "off";
                app.D2_2.Visible = "off";
                app.D2_3.Visible = "off";
                app.D2_4.Visible = "off";
                app.DL3.Visible = "off";
                app.LabelD3.Visible = "off";
                app.LabelD3_2.Visible = "off";
                app.LabelD3_3.Visible = "off";
                app.LabelD3_4.Visible = "off";
                app.D3_1.Visible = "off";
                app.D3_2.Visible = "off";
                app.D3_3.Visible = "off";
                app.D3_4.Visible = "off";
                app.DL4.Visible = "off";
                app.LabelD4.Visible = "off";
                app.LabelD4_2.Visible = "off";
                app.LabelD4_3.Visible = "off";
                app.LabelD4_4.Visible = "off";
                app.D4_1.Visible = "off";
                app.D4_2.Visible = "off";
                app.D4_3.Visible = "off";
                app.D4_4.Visible = "off";
                app.DL5.Visible = "off";
                app.LabelD5.Visible = "off";
                app.LabelD5_2.Visible = "off";
                app.LabelD5_3.Visible = "off";
                app.LabelD5_4.Visible = "off";
                app.D5_1.Visible = "off";
                app.D5_2.Visible = "off";
                app.D5_3.Visible = "off";
                app.D5_4.Visible = "off";
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Distancia lateral";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Apertura Númerica";
                    app.LabelD1_4.Text = "Ángulo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD1.Text = "Apertura Númerica";
                    app.LabelD1_2.Text = "Índice de refracción";
                    app.LabelD1_3.Text = "Separación";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    app.LabelD1.Text = "Radio 1";
                    app.LabelD1_2.Text = "Radio 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD1.Text = "Apertura Númerica 1";
                    app.LabelD1_2.Text = "Apertura Númerica 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                end
            elseif app.N.Value == 2
                app.DL3.Visible = "off";
                app.LabelD3.Visible = "off";
                app.LabelD3_2.Visible = "off";
                app.LabelD3_3.Visible = "off";
                app.LabelD3_4.Visible = "off";
                app.D3_1.Visible = "off";
                app.D3_2.Visible = "off";
                app.D3_3.Visible = "off";
                app.D3_4.Visible = "off";
                app.DL4.Visible = "off";
                app.LabelD4.Visible = "off";
                app.LabelD4_2.Visible = "off";
                app.LabelD4_3.Visible = "off";
                app.LabelD4_4.Visible = "off";
                app.D4_1.Visible = "off";
                app.D4_2.Visible = "off";
                app.D4_3.Visible = "off";
                app.D4_4.Visible = "off";
                app.DL5.Visible = "off";
                app.LabelD5.Visible = "off";
                app.LabelD5_2.Visible = "off";
                app.LabelD5_3.Visible = "off";
                app.LabelD5_4.Visible = "off";
                app.D5_1.Visible = "off";
                app.D5_2.Visible = "off";
                app.D5_3.Visible = "off";
                app.D5_4.Visible = "off";
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Distancia lateral";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Apertura Númerica";
                    app.LabelD1_4.Text = "Ángulo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD1.Text = "Apertura Númerica";
                    app.LabelD1_2.Text = "Índice de refracción";
                    app.LabelD1_3.Text = "Separación";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    app.LabelD1.Text = "Radio 1";
                    app.LabelD1_2.Text = "Radio 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD1.Text = "Apertura Númerica 1";
                    app.LabelD1_2.Text = "Apertura Númerica 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                end
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Distancia lateral";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Apertura Númerica";
                    app.LabelD2_4.Text = "Ángulo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD2.Text = "Apertura Númerica";
                    app.LabelD2_2.Text = "Índice de refracción";
                    app.LabelD2_3.Text = "Separación";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    app.LabelD2.Text = "Radio 1";
                    app.LabelD2_2.Text = "Radio 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD2.Text = "Apertura Númerica 1";
                    app.LabelD2_2.Text = "Apertura Númerica 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                end
            elseif app.N.Value == 3
                app.DL4.Visible = "off";
                app.LabelD4.Visible = "off";
                app.LabelD4_2.Visible = "off";
                app.LabelD4_3.Visible = "off";
                app.LabelD4_4.Visible = "off";
                app.D4_1.Visible = "off";
                app.D4_2.Visible = "off";
                app.D4_3.Visible = "off";
                app.D4_4.Visible = "off";
                app.DL5.Visible = "off";
                app.LabelD5.Visible = "off";
                app.LabelD5_2.Visible = "off";
                app.LabelD5_3.Visible = "off";
                app.LabelD5_4.Visible = "off";
                app.D5_1.Visible = "off";
                app.D5_2.Visible = "off";
                app.D5_3.Visible = "off";
                app.D5_4.Visible = "off";
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Distancia lateral";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Apertura Númerica";
                    app.LabelD1_4.Text = "Ángulo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD1.Text = "Apertura Númerica";
                    app.LabelD1_2.Text = "Índice de refracción";
                    app.LabelD1_3.Text = "Separación";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    app.LabelD1.Text = "Radio 1";
                    app.LabelD1_2.Text = "Radio 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD1.Text = "Apertura Númerica 1";
                    app.LabelD1_2.Text = "Apertura Númerica 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                end
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Distancia lateral";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Apertura Númerica";
                    app.LabelD2_4.Text = "Ángulo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD2.Text = "Apertura Númerica";
                    app.LabelD2_2.Text = "Índice de refracción";
                    app.LabelD2_3.Text = "Separación";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    app.LabelD2.Text = "Radio 1";
                    app.LabelD2_2.Text = "Radio 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD2.Text = "Apertura Númerica 1";
                    app.LabelD2_2.Text = "Apertura Númerica 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                end
                if app.Desalineacin3DropDown.Value == "Desalineación Lateral"
                    app.LabelD3.Text = "Índice de refracción 1";
                    app.LabelD3_2.Text = "Índice de refracción 2";
                    app.LabelD3_3.Text = "Distancia lateral";
                    app.LabelD3_4.Text = "Radio del núcleo";
                elseif app.Desalineacin3DropDown.Value == "Desalineación Angular"
                    app.LabelD3.Text = "Índice de refracción 1";
                    app.LabelD3_2.Text = "Índice de refracción 2";
                    app.LabelD3_3.Text = "Apertura Númerica";
                    app.LabelD3_4.Text = "Ángulo";
                elseif app.Desalineacin3DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD3.Text = "Apertura Númerica";
                    app.LabelD3_2.Text = "Índice de refracción";
                    app.LabelD3_3.Text = "Separación";
                    app.LabelD3_4.Text = "Radio del núcleo";
                elseif app.Desalineacin3DropDown.Value == "Diámetros Diferentes"
                    app.LabelD3.Text = "Radio 1";
                    app.LabelD3_2.Text = "Radio 2";
                    app.LabelD3_3.Visible = "off";
                    app.D3_3.Visible = "off";
                    app.LabelD3_4.Visible = "off";
                    app.D3_4.Visible = "off";
                elseif app.Desalineacin3DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD3.Text = "Apertura Númerica 1";
                    app.LabelD3_2.Text = "Apertura Númerica 2";
                    app.LabelD3_3.Visible = "off";
                    app.D3_3.Visible = "off";
                    app.LabelD3_4.Visible = "off";
                    app.D3_4.Visible = "off";
                end
            elseif app.N.Value == 4
                app.DL5.Visible = "off";
                app.LabelD5.Visible = "off";
                app.LabelD5_2.Visible = "off";
                app.LabelD5_3.Visible = "off";
                app.LabelD5_4.Visible = "off";
                app.D5_1.Visible = "off";
                app.D5_2.Visible = "off";
                app.D5_3.Visible = "off";
                app.D5_4.Visible = "off";
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Distancia lateral";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Apertura Númerica";
                    app.LabelD1_4.Text = "Ángulo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD1.Text = "Apertura Númerica";
                    app.LabelD1_2.Text = "Índice de refracción";
                    app.LabelD1_3.Text = "Separación";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    app.LabelD1.Text = "Radio 1";
                    app.LabelD1_2.Text = "Radio 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD1.Text = "Apertura Númerica 1";
                    app.LabelD1_2.Text = "Apertura Númerica 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                end
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Distancia lateral";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Apertura Númerica";
                    app.LabelD2_4.Text = "Ángulo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD2.Text = "Apertura Númerica";
                    app.LabelD2_2.Text = "Índice de refracción";
                    app.LabelD2_3.Text = "Separación";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    app.LabelD2.Text = "Radio 1";
                    app.LabelD2_2.Text = "Radio 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD2.Text = "Apertura Númerica 1";
                    app.LabelD2_2.Text = "Apertura Númerica 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                end
                if app.Desalineacin3DropDown.Value == "Desalineación Lateral"
                    app.LabelD3.Text = "Índice de refracción 1";
                    app.LabelD3_2.Text = "Índice de refracción 2";
                    app.LabelD3_3.Text = "Distancia lateral";
                    app.LabelD3_4.Text = "Radio del núcleo";
                elseif app.Desalineacin3DropDown.Value == "Desalineación Angular"
                    app.LabelD3.Text = "Índice de refracción 1";
                    app.LabelD3_2.Text = "Índice de refracción 2";
                    app.LabelD3_3.Text = "Apertura Númerica";
                    app.LabelD3_4.Text = "Ángulo";
                elseif app.Desalineacin3DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD3.Text = "Apertura Númerica";
                    app.LabelD3_2.Text = "Índice de refracción";
                    app.LabelD3_3.Text = "Separación";
                    app.LabelD3_4.Text = "Radio del núcleo";
                elseif app.Desalineacin3DropDown.Value == "Diámetros Diferentes"
                    app.LabelD3.Text = "Radio 1";
                    app.LabelD3_2.Text = "Radio 2";
                    app.LabelD3_3.Visible = "off";
                    app.D3_3.Visible = "off";
                    app.LabelD3_4.Visible = "off";
                    app.D3_4.Visible = "off";
                elseif app.Desalineacin3DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD3.Text = "Apertura Númerica 1";
                    app.LabelD3_2.Text = "Apertura Númerica 2";
                    app.LabelD3_3.Visible = "off";
                    app.D3_3.Visible = "off";
                    app.LabelD3_4.Visible = "off";
                    app.D3_4.Visible = "off";
                end
                if app.Desalineacin4DropDown.Value == "Desalineación Lateral"
                    app.LabelD4.Text = "Índice de refracción 1";
                    app.LabelD4_2.Text = "Índice de refracción 2";
                    app.LabelD4_3.Text = "Distancia lateral";
                    app.LabelD4_4.Text = "Radio del núcleo";
                elseif app.Desalineacin4DropDown.Value == "Desalineación Angular"
                    app.LabelD4.Text = "Índice de refracción 1";
                    app.LabelD4_2.Text = "Índice de refracción 2";
                    app.LabelD4_3.Text = "Apertura Númerica";
                    app.LabelD4_4.Text = "Ángulo";
                elseif app.Desalineacin4DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD4.Text = "Apertura Númerica";
                    app.LabelD4_2.Text = "Índice de refracción";
                    app.LabelD4_3.Text = "Separación";
                    app.LabelD4_4.Text = "Radio del núcleo";
                elseif app.Desalineacin4DropDown.Value == "Diámetros Diferentes"
                    app.LabelD4.Text = "Radio 1";
                    app.LabelD4_2.Text = "Radio 2";
                    app.LabelD4_3.Visible = "off";
                    app.D4_3.Visible = "off";
                    app.LabelD4_4.Visible = "off";
                    app.D4_4.Visible = "off";
                elseif app.Desalineacin4DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD4.Text = "Apertura Númerica 1";
                    app.LabelD4_2.Text = "Apertura Númerica 2";
                    app.LabelD4_3.Visible = "off";
                    app.D4_3.Visible = "off";
                    app.LabelD4_4.Visible = "off";
                    app.D4_4.Visible = "off";
                end
            elseif app.N.Value == 5
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Distancia lateral";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    app.LabelD1.Text = "Índice de refracción 1";
                    app.LabelD1_2.Text = "Índice de refracción 2";
                    app.LabelD1_3.Text = "Apertura Númerica";
                    app.LabelD1_4.Text = "Ángulo";
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD1.Text = "Apertura Númerica";
                    app.LabelD1_2.Text = "Índice de refracción";
                    app.LabelD1_3.Text = "Separación";
                    app.LabelD1_4.Text = "Radio del núcleo";
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    app.LabelD1.Text = "Radio 1";
                    app.LabelD1_2.Text = "Radio 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD1.Text = "Apertura Númerica 1";
                    app.LabelD1_2.Text = "Apertura Númerica 2";
                    app.LabelD1_3.Visible = "off";
                    app.D1_3.Visible = "off";
                    app.LabelD1_4.Visible = "off";
                    app.D1_4.Visible = "off";
                end
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Distancia lateral";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    app.LabelD2.Text = "Índice de refracción 1";
                    app.LabelD2_2.Text = "Índice de refracción 2";
                    app.LabelD2_3.Text = "Apertura Númerica";
                    app.LabelD2_4.Text = "Ángulo";
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD2.Text = "Apertura Númerica";
                    app.LabelD2_2.Text = "Índice de refracción";
                    app.LabelD2_3.Text = "Separación";
                    app.LabelD2_4.Text = "Radio del núcleo";
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    app.LabelD2.Text = "Radio 1";
                    app.LabelD2_2.Text = "Radio 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD2.Text = "Apertura Númerica 1";
                    app.LabelD2_2.Text = "Apertura Númerica 2";
                    app.LabelD2_3.Visible = "off";
                    app.D2_3.Visible = "off";
                    app.LabelD2_4.Visible = "off";
                    app.D2_4.Visible = "off";
                end
                if app.Desalineacin3DropDown.Value == "Desalineación Lateral"
                    app.LabelD3.Text = "Índice de refracción 1";
                    app.LabelD3_2.Text = "Índice de refracción 2";
                    app.LabelD3_3.Text = "Distancia lateral";
                    app.LabelD3_4.Text = "Radio del núcleo";
                elseif app.Desalineacin3DropDown.Value == "Desalineación Angular"
                    app.LabelD3.Text = "Índice de refracción 1";
                    app.LabelD3_2.Text = "Índice de refracción 2";
                    app.LabelD3_3.Text = "Apertura Númerica";
                    app.LabelD3_4.Text = "Ángulo";
                elseif app.Desalineacin3DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD3.Text = "Apertura Númerica";
                    app.LabelD3_2.Text = "Índice de refracción";
                    app.LabelD3_3.Text = "Separación";
                    app.LabelD3_4.Text = "Radio del núcleo";
                elseif app.Desalineacin3DropDown.Value == "Diámetros Diferentes"
                    app.LabelD3.Text = "Radio 1";
                    app.LabelD3_2.Text = "Radio 2";
                    app.LabelD3_3.Visible = "off";
                    app.D3_3.Visible = "off";
                    app.LabelD3_4.Visible = "off";
                    app.D3_4.Visible = "off";
                elseif app.Desalineacin3DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD3.Text = "Apertura Númerica 1";
                    app.LabelD3_2.Text = "Apertura Númerica 2";
                    app.LabelD3_3.Visible = "off";
                    app.D3_3.Visible = "off";
                    app.LabelD3_4.Visible = "off";
                    app.D3_4.Visible = "off";
                end
                if app.Desalineacin4DropDown.Value == "Desalineación Lateral"
                    app.LabelD4.Text = "Índice de refracción 1";
                    app.LabelD4_2.Text = "Índice de refracción 2";
                    app.LabelD4_3.Text = "Distancia lateral";
                    app.LabelD4_4.Text = "Radio del núcleo";
                elseif app.Desalineacin4DropDown.Value == "Desalineación Angular"
                    app.LabelD4.Text = "Índice de refracción 1";
                    app.LabelD4_2.Text = "Índice de refracción 2";
                    app.LabelD4_3.Text = "Apertura Númerica";
                    app.LabelD4_4.Text = "Ángulo";
                elseif app.Desalineacin4DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD4.Text = "Apertura Númerica";
                    app.LabelD4_2.Text = "Índice de refracción";
                    app.LabelD4_3.Text = "Separación";
                    app.LabelD4_4.Text = "Radio del núcleo";
                elseif app.Desalineacin4DropDown.Value == "Diámetros Diferentes"
                    app.LabelD4.Text = "Radio 1";
                    app.LabelD4_2.Text = "Radio 2";
                    app.LabelD4_3.Visible = "off";
                    app.D4_3.Visible = "off";
                    app.LabelD4_4.Visible = "off";
                    app.D4_4.Visible = "off";
                elseif app.Desalineacin4DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD4.Text = "Apertura Númerica 1";
                    app.LabelD4_2.Text = "Apertura Númerica 2";
                    app.LabelD4_3.Visible = "off";
                    app.D4_3.Visible = "off";
                    app.LabelD4_4.Visible = "off";
                    app.D4_4.Visible = "off";
                end
                if app.Desalineacin5DropDown.Value == "Desalineación Lateral"
                    app.LabelD5.Text = "Índice de refracción 1";
                    app.LabelD5_2.Text = "Índice de refracción 2";
                    app.LabelD5_3.Text = "Distancia lateral";
                    app.LabelD5_4.Text = "Radio del núcleo";
                elseif app.Desalineacin5DropDown.Value == "Desalineación Angular"
                    app.LabelD5.Text = "Índice de refracción 1";
                    app.LabelD5_2.Text = "Índice de refracción 2";
                    app.LabelD5_3.Text = "Apertura Númerica";
                    app.LabelD5_4.Text = "Ángulo";
                elseif app.Desalineacin5DropDown.Value == "Desalineación Longitudinal"
                    app.LabelD5.Text = "Apertura Númerica";
                    app.LabelD5_2.Text = "Índice de refracción";
                    app.LabelD5_3.Text = "Separación";
                    app.LabelD5_4.Text = "Radio del núcleo";
                elseif app.Desalineacin5DropDown.Value == "Diámetros Diferentes"
                    app.LabelD5.Text = "Radio 1";
                    app.LabelD5_2.Text = "Radio 2";
                    app.LabelD5_3.Visible = "off";
                    app.D5_3.Visible = "off";
                    app.LabelD5_4.Visible = "off";
                    app.D5_4.Visible = "off";
                elseif app.Desalineacin5DropDown.Value == "Diferencia de Aperturas Numéricas"
                    app.LabelD5.Text = "Apertura Númerica 1";
                    app.LabelD5_2.Text = "Apertura Númerica 2";
                    app.LabelD5_3.Visible = "off";
                    app.D5_3.Visible = "off";
                    app.LabelD5_4.Visible = "off";
                    app.D5_4.Visible = "off";
                end
            end
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.Panel_3.Visible = "off";
            app.Panel_4.Visible = "on";
            V=zeros(1,app.N.Value);
            i = 1;
            V(i) = app.PerdidasL(app.Ate.Value,app.L.Value);
            app.PerdidasPorL.Text = strcat("Perdidas por coeficiente y distancia: ",num2str(V(i))," dB");
            if app.N.Value == 1
                app.R2.Visible = "off";
                app.R3.Visible = "off";
                app.R4.Visible = "off";
                app.R5.Visible = "off";
                i=i+1;
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                end
            elseif app.N.Value == 2
                app.R3.Visible = "off";
                app.R4.Visible = "off";
                app.R5.Visible = "off";
                i=i+1;
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                end
            elseif app.N.Value == 3
                app.R4.Visible = "off";
                app.R5.Visible = "off";
                i=i+1;
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin3DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D3_1.Value,app.D3_2.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D3_1.Value,app.D3_2.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                end
            elseif app.N.Value == 4
                app.R5.Visible = "off";
                i=i+1;
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin3DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D3_1.Value,app.D3_2.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D3_1.Value,app.D3_2.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin4DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D4_1.Value,app.D4_2.Value,app.D4_3.Value,app.D4_4.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D4_1.Value,app.D4_2.Value,app.D4_3.Value,app.D4_4.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D4_1.Value,app.D4_2.Value,app.D4_3.Value,app.D4_4.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D4_1.Value,app.D4_2.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D4_1.Value,app.D4_2.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                end
            elseif app.N.Value == 5
                i=i+1;
                if app.Desalineacin1DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D1_1.Value,app.D1_2.Value,app.D1_3.Value,app.D1_4.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                elseif app.Desalineacin1DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D1_1.Value,app.D1_2.Value);
                    app.R1.Text = strcat("Pérdidas desalineación 1: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin2DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D2_1.Value,app.D2_2.Value,app.D2_3.Value,app.D2_4.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                elseif app.Desalineacin2DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D2_1.Value,app.D2_2.Value);
                    app.R2.Text = strcat("Pérdidas desalineación 2: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin3DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D3_1.Value,app.D3_2.Value,app.D3_3.Value,app.D3_4.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D3_1.Value,app.D3_2.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                elseif app.Desalineacin3DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D3_1.Value,app.D3_2.Value);
                    app.R3.Text = strcat("Pérdidas desalineación 3: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin4DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D4_1.Value,app.D4_2.Value,app.D4_3.Value,app.D4_4.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D4_1.Value,app.D4_2.Value,app.D4_3.Value,app.D4_4.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D4_1.Value,app.D4_2.Value,app.D4_3.Value,app.D4_4.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D4_1.Value,app.D4_2.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                elseif app.Desalineacin4DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D4_1.Value,app.D4_2.Value);
                    app.R4.Text = strcat("Pérdidas desalineación 4: ",num2str(V(i))," dB");
                end
                i=i+1;
                if app.Desalineacin5DropDown.Value == "Desalineación Lateral"
                    V(i) = app.Lateral(app.D5_1.Value,app.D5_2.Value,app.D5_3.Value,app.D5_4.Value);
                    app.R5.Text = strcat("Pérdidas desalineación 5: ",num2str(V(i))," dB");
                elseif app.Desalineacin5DropDown.Value == "Desalineación Angular"
                    V(i) = app.Angular(app.D5_1.Value,app.D5_2.Value,app.D5_3.Value,app.D5_4.Value);
                    app.R5.Text = strcat("Pérdidas desalineación 5: ",num2str(V(i))," dB");
                elseif app.Desalineacin5DropDown.Value == "Desalineación Longitudinal"
                    V(i) = app.Longitudinal(app.D5_1.Value,app.D5_2.Value,app.D5_3.Value,app.D5_4.Value);
                    app.R5.Text = strcat("Pérdidas desalineación 5: ",num2str(V(i))," dB");
                elseif app.Desalineacin5DropDown.Value == "Diámetros Diferentes"
                    V(i) = app.Diametros(app.D5_1.Value,app.D5_2.Value);
                    app.R5.Text = strcat("Pérdidas desalineación 5: ",num2str(V(i))," dB");
                elseif app.Desalineacin5DropDown.Value == "Diferencia de Aperturas Numéricas"
                    V(i) = app.Aperturas(app.D5_1.Value,app.D5_2.Value);
                    app.R5.Text = strcat("Pérdidas desalineación 5: ",num2str(V(i))," dB");
                end
            end
            LTotal = app.PerdidasT(V,app.N.Value);
            app.LT.Text = strcat("Pérdidas Totales: ",num2str(LTotal));
            PSalida = app.PotenciaSalida(app.Pe.Value,LTotal);
            app.PS.Text = strcat("Potencia de sálida: ",num2str(PSalida));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Desalineacin1DropDownLabel = uilabel(app.Panel_2);
            app.Desalineacin1DropDownLabel.Text = 'Desalineación 1';
            app.Desalineacin1DropDown = uidropdown(app.Panel_2);
            app.Desalineacin1DropDown.Items = {'Desalineación Lateral', 'Desalineación Angular', 'Desalineación Longitudinal', 'Diámetros Diferentes', 'Diferencia de Aperturas Numéricas'};
            app.Desalineacin1DropDown.Value = 'Desalineación Lateral';
            app.NextButton_2 = uibutton(app.Panel_2, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.Desalineacin2DropDownLabel = uilabel(app.Panel_2);
            app.Desalineacin2DropDownLabel.Text = 'Desalineación 2';
            app.Desalineacin2DropDown = uidropdown(app.Panel_2);
            app.Desalineacin2DropDown.Items = {'Desalineación Lateral', 'Desalineación Angular', 'Desalineación Longitudinal', 'Diámetros Diferentes', 'Diferencia de Aperturas Numéricas'};
            app.Desalineacin2DropDown.Value = 'Desalineación Lateral';
            app.Desalineacin3DropDownLabel = uilabel(app.Panel_2);
            app.Desalineacin3DropDownLabel.Text = 'Desalineación 3';
            app.Desalineacin3DropDown = uidropdown(app.Panel_2);
            app.Desalineacin3DropDown.Items = {'Desalineación Lateral', 'Desalineación Angular', 'Desalineación Longitudinal', 'Diámetros Diferentes', 'Diferencia de Aperturas Numéricas'};
            app.Desalineacin3DropDown.Value = 'Desalineación Lateral';
            app.Desalineacin5DropDownLabel = uilabel(app.Panel_2);
            app.Desalineacin5DropDownLabel.Text = 'Desalineación 5';
            app.Desalineacin5DropDown = uidropdown(app.Panel_2);
            app.Desalineacin5DropDown.Items = {'Desalineación Lateral', 'Desalineación Angular', 'Desalineación Longitudinal', 'Diámetros Diferentes', 'Diferencia de Aperturas Numéricas'};
            app.Desalineacin5DropDown.Value = 'Desalineación Lateral';
            app.Desalineacin4DropDownLabel = uilabel(app.Panel_2);
            app.Desalineacin4DropDownLabel.Text = 'Desalineación 4';
            app.Desalineacin4DropDown = uidropdown(app.Panel_2);
            app.Desalineacin4DropDown.Items = {'Desalineación Lateral', 'Desalineación Angular', 'Desalineación Longitudinal', 'Diámetros Diferentes', 'Diferencia de Aperturas Numéricas'};
            app.Desalineacin4DropDown.Value = 'Desalineación Lateral';
            app.Panel_3 = uipanel(app.UIFigure);
            app.D1_2 = uieditfield(app.Panel_3, 'numeric');
            app.D1_3 = uieditfield(app.Panel_3, 'numeric');
            app.D1_4 = uieditfield(app.Panel_3, 'numeric');
            app.D1_1 = uieditfield(app.Panel_3, 'numeric');
            app.DL1 = uilabel(app.Panel_3);
            app.DL1.Text = 'Desalienación 1';
            app.D2_2 = uieditfield(app.Panel_3, 'numeric');
            app.D2_3 = uieditfield(app.Panel_3, 'numeric');
            app.D2_4 = uieditfield(app.Panel_3, 'numeric');
            app.D2_1 = uieditfield(app.Panel_3, 'numeric');
            app.DL2 = uilabel(app.Panel_3);
            app.DL2.Text = 'Desalienación 2';
            app.D3_2 = uieditfield(app.Panel_3, 'numeric');
            app.D3_3 = uieditfield(app.Panel_3, 'numeric');
            app.D3_4 = uieditfield(app.Panel_3, 'numeric');
            app.D3_1 = uieditfield(app.Panel_3, 'numeric');
            app.DL3 = uilabel(app.Panel_3);
            app.DL3.Text = 'Desalienación 3';
            app.D4_2 = uieditfield(app.Panel_3, 'numeric');
            app.D4_3 = uieditfield(app.Panel_3, 'numeric');
            app.D4_4 = uieditfield(app.Panel_3, 'numeric');
            app.D4_1 = uieditfield(app.Panel_3, 'numeric');
            app.DL4 = uilabel(app.Panel_3);
            app.DL4.Text = 'Desalienación 4';
            app.D5_2 = uieditfield(app.Panel_3, 'numeric');
            app.D5_3 = uieditfield(app.Panel_3, 'numeric');
            app.D5_4 = uieditfield(app.Panel_3, 'numeric');
            app.D5_1 = uieditfield(app.Panel_3, 'numeric');
            app.DL5 = uilabel(app.Panel_3);
            app.DL5.Text = 'Desalienación 5';
            app.NextButton_3 = uibutton(app.Panel_3, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.LabelD1 = uilabel(app.Panel_3);
            app.LabelD1_2 = uilabel(app.Panel_3);
            app.LabelD1_3 = uilabel(app.Panel_3);
            app.LabelD1_4 = uilabel(app.Panel_3);
            app.LabelD2 = uilabel(app.Panel_3);
            app.LabelD2_2 = uilabel(app.Panel_3);
            app.LabelD2_3 = uilabel(app.Panel_3);
            app.LabelD2_4 = uilabel(app.Panel_3);
            app.LabelD3 = uilabel(app.Panel_3);
            app.LabelD4 = uilabel(app.Panel_3);
            app.LabelD5 = uilabel(app.Panel_3);
            app.LabelD3_2 = uilabel(app.Panel_3);
            app.LabelD4_2 = uilabel(app.Panel_3);
            app.LabelD5_2 = uilabel(app.Panel_3);
            app.LabelD3_3 = uilabel(app.Panel_3);
            app.LabelD4_3 = uilabel(app.Panel_3);
            app.LabelD5_3 = uilabel(app.Panel_3);
            app.LabelD3_4 = uilabel(app.Panel_3);
            app.LabelD4_4 = uilabel(app.Panel_3);
            app.LabelD5_4 = uilabel(app.Panel_3);
            app.Anuncio = uilabel(app.Panel_3);
            app.Anuncio.Text = 'Digite los datos que se requieran para realizar los cálculos:';
            app.Panel_4 = uipanel(app.UIFigure);
            app.ResultadosLabel = uilabel(app.Panel_4);
            app.ResultadosLabel.Text = 'Resultados';
            app.R1 = uilabel(app.Panel_4);
            app.R1.Text = 'Label1';
            app.R2 = uilabel(app.Panel_4);
            app.R2.Text = 'Label1';
            app.R3 = uilabel(app.Panel_4);
            app.R3.Text = 'Label1';
            app.R4 = uilabel(app.Panel_4);
            app.R4.Text = 'Label1';
            app.R5 = uilabel(app.Panel_4);
            app.R5.Text = 'Label1';
            app.PerdidasPorL = uilabel(app.Panel_4);
            app.PerdidasPorL.Text = 'Label1';
            app.LT = uilabel(app.Panel_4);
            app.LT.Text = 'Label1';
            app.PS = uilabel(app.Panel_4);
            app.PS.Text = 'Label1';
            app.Panel = uipanel(app.UIFigure);
            app.QueHacePrograma = uilabel(app.Panel);
            app.QueHacePrograma.Text = 'Bienvenid@, este programa ayuda a realizar cálculos de pérdidas por desalineaciones.';
            app.Creditos = uilabel(app.Panel);
            app.Creditos.Text = 'Desarrollado por Andrei Riaño y Andrés Linares';
            app.QueHacePrograma_2 = uilabel(app.Panel);
            app.QueHacePrograma_2.Text = {'Como bien se sabe, la fibra óptica puede presentar distintos tipos de pérdidas, en este caso'; 'se tienen en cuenta las pérdidas por desalienación, así como las que se tienen por el coeficiente'; 'de atenuación y se comprueba que tan eficiente es, de acuerdo a la potencia de entrada y la que'; 'requiere en su salida.'};
            app.Ingrese = uilabel(app.Panel);
            app.Ingrese.Text = 'Por favor ingrese los siguientes datos:';
            app.PotenciadeentradaLabel = uilabel(app.Panel);
            app.PotenciadeentradaLabel.Text = 'Potencia de entrada';
            app.Pe = uieditfield(app.Panel, 'numeric');
            app.LongituddelafibraKmEditFieldLabel = uilabel(app.Panel);
            app.LongituddelafibraKmEditFieldLabel.Text = 'Longitud de la fibra (Km)';
            app.L = uieditfield(app.Panel, 'numeric');
            app.CoeficientedeatenuacindBKmEditFieldLabel = uilabel(app.Panel);
            app.CoeficientedeatenuacindBKmEditFieldLabel.Text = 'Coeficiente de atenuación (dB/Km)';
            app.Ate = uieditfield(app.Panel, 'numeric');
            app.NmerodedesalineacionesLabel = uilabel(app.Panel);
            app.NmerodedesalineacionesLabel.Text = 'Número de desalineaciones';
            app.N = uieditfield(app.Panel, 'numeric');
            app.NextButton = uibutton(app.Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Desalineaciones

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end