classdef PerdidasyFotodetectetor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        IngresodedatosPanel         matlab.ui.container.Panel
        Indicen1EditFieldLabel      matlab.ui.control.Label
        n1                          matlab.ui.control.NumericEditField
        Indicen2EditFieldLabel      matlab.ui.control.Label
        n2                          matlab.ui.control.NumericEditField
        NAEditFieldLabel            matlab.ui.control.Label
        NA                          matlab.ui.control.NumericEditField
        LambdamEditFieldLabel       matlab.ui.control.Label
        Lambda                      matlab.ui.control.NumericEditField
        FrecuenciaHzEditFieldLabel  matlab.ui.control.Label
        F                           matlab.ui.control.NumericEditField
        DiametromEditFieldLabel     matlab.ui.control.Label
        D                           matlab.ui.control.NumericEditField
        LKmEditFieldLabel           matlab.ui.control.Label
        L                           matlab.ui.control.NumericEditField
        AlphadBKmEditFieldLabel     matlab.ui.control.Label
        Alpha                       matlab.ui.control.NumericEditField
        PtxWLabel                   matlab.ui.control.Label
        Ptx                         matlab.ui.control.NumericEditField
        PrxWEditFieldLabel          matlab.ui.control.Label
        Prx                         matlab.ui.control.NumericEditField
        LconectordBLabel            matlab.ui.control.Label
        Lconector                   matlab.ui.control.NumericEditField
        LuniondBEditFieldLabel      matlab.ui.control.Label
        Lunion                      matlab.ui.control.NumericEditField
        UnionesEditFieldLabel       matlab.ui.control.Label
        Uniones                     matlab.ui.control.NumericEditField
        T                           matlab.ui.control.NumericEditField
        TKEditFieldLabel            matlab.ui.control.Label
        DF                          matlab.ui.control.NumericEditField
        DFHzEditFieldLabel          matlab.ui.control.Label
        RL                          matlab.ui.control.NumericEditField
        RLOhmsLabel                 matlab.ui.control.Label
        Eficiencia                  matlab.ui.control.NumericEditField
        nefcuantLabel               matlab.ui.control.Label
        Pe                          matlab.ui.control.NumericEditField
        PeLabel                     matlab.ui.control.Label
        Id                          matlab.ui.control.NumericEditField
        IdALabel                    matlab.ui.control.Label
        p                           matlab.ui.control.NumericEditField
        pAWLabel                    matlab.ui.control.Label
        VB                          matlab.ui.control.NumericEditField
        VBVLabel                    matlab.ui.control.Label
        DesalineacionesPanel        matlab.ui.container.Panel
        De1                         matlab.ui.control.NumericEditField
        De2                         matlab.ui.control.NumericEditField
        De3                         matlab.ui.control.NumericEditField
        TipoDropDownLabel           matlab.ui.control.Label
        Des1                        matlab.ui.control.DropDown
        TipoDropDown_2Label         matlab.ui.control.Label
        Des2                        matlab.ui.control.DropDown
        TipoDropDown_3Label         matlab.ui.control.Label
        Des3                        matlab.ui.control.DropDown
        Calcular                    matlab.ui.control.Button
        Lt                          matlab.ui.control.Label
        Circuito                    matlab.ui.control.Image
        Pmax                        matlab.ui.control.Label
        Is                          matlab.ui.control.Label
        SN                          matlab.ui.control.Label
        CircuitodetectorPINLabel    matlab.ui.control.Label
        PES_Label                   matlab.ui.control.Label
        PNT_Label                   matlab.ui.control.Label
        PNS_Label                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calcular
        function CalcularButtonPushed(app, event)
            if app.n1.Value ~= 0 && app.n2.Value ~= 0
                app.NA.Value = Na(app.n1.Value,app.n2.Value);
            end
            if app.n1.Value ~= 0 && app.NA.Value ~= 0
                app.n2.Value = sqrt(app.n1.Value^2-app.NA.Value^2);
            end
            if app.n2.Value ~=0 && app.NA.Value ~= 0
                app.n1.Value = sqrt(app.NA.Value^2+app.n2.Value^2);
            end
            if app.F.Value ~= 0
                app.Lambda.Value = LambdaFrecuencia(app.F.Value);
            elseif app.Lambda.Value ~= 0
                app.F.Value = LambdaFrecuencia(app.Lambda.Value);
            end
            opc = ["Lateral (m)", "Angular (°)", "Longitudinal (m)"];
            LT = 0;
            for x = 1:3
                if x == 1
                     if app.Des1.Value == opc(1,x) && app.De1.Value ~= 0
                         LT = Lateral(app.n1.Value,app.n2.Value,app.De1.Value,app.D.Value/2) + LT;
                     end
                     if app.Des2.Value == opc(1,x) && app.De2.Value ~= 0
                         LT = Lateral(app.n1.Value,app.n2.Value,app.De2.Value,app.D.Value/2) + LT;
                     end
                     if app.Des3.Value == opc(1,x) && app.De3.Value ~= 0
                         LT = Lateral(app.n1.Value,app.n2.Value,app.De3.Value,app.D.Value/2) + LT;
                     end
                elseif x == 2
                     if app.Des1.Value == opc(1,x) && app.De1.Value ~= 0
                         LT = Angular(app.n1.Value,app.n2.Value,app.NA.Value,app.De1.Value) + LT;
                     end
                     if app.Des2.Value == opc(1,x) && app.De2.Value ~= 0
                         LT = Angular(app.n1.Value,app.n2.Value,app.NA.Value,app.De2.Value) + LT;
                     end
                     if app.Des3.Value == opc(1,x) && app.De3.Value ~= 0
                         LT = Angular(app.n1.Value,app.n2.Value,app.NA.Value,app.De3.Value) + LT;
                     end
                elseif x == 3
                     if app.Des1.Value == opc(1,x) && app.De1.Value ~= 0
                         LT = Longitudinal(app.NA.Value,app.De1.Value,app.D.Value/2) + LT;
                     end
                     if app.Des2.Value == opc(1,x) && app.De2.Value ~= 0
                         LT = Longitudinal(app.NA.Value,app.De2.Value,app.D.Value/2) + LT;
                     end
                     if app.Des3.Value == opc(1,x) && app.De3.Value ~= 0
                         LT = Longitudinal(app.NA.Value,app.De3.Value,app.D.Value/2) + LT;
                     end
                end
            end
            if app.L.Value ~= 0 &&  app.Alpha.Value ~= 0
                LT = LT + app.L.Value*app.Alpha.Value;
            end
            if app.Lconector.Value ~= 0
                LT = LT + app.Lconector.Value*2;
            end
            if app.Lunion.Value ~= 0
                LT = LT + app.Lunion.Value*app.Uniones.Value;
            end
            LTexto = "Perdidas Totales: ";
            if LT ~= 0
                app.Lt.Text = strcat(LTexto,num2str(LT)," dB");
                PotRx = 10^(((10*log10(app.Ptx.Value/1e-3))-LT)/1e-3);
                if (app.Prx.Value == 0 && app.Ptx.Value ~= 0) || (app.Prx.Value ~= PotRx)
                    Ptransmision = 10*log10(app.Ptx.Value/1e-3);
                    Precepcion = Ptransmision - LT;
                    app.Prx.Value = 10^(Precepcion/10)*1e-3;
                end
                if app.Ptx.Value == 0 && app.Prx.Value ~= 0
                    Precepcion = 10*log10(app.Prx.Value/1e-3);
                    Ptransmision = Precepcion + LT;
                    app.Ptx.Value = 10^(Ptransmision/10)*1e-3;
                end
            else
                app.Lt.Text = strcat(LTexto,num2str(LT)," dB");
                if app.Ptx.Value ~= 0
                    app.Prx.Value = app.Ptx.Value;
                else
                    app.Ptx.Value = app.Prx.Value;
                end
            end
            if app.VB.Value ~= 0 && app.p.Value ~=0 && app.RL.Value ~= 0
                app.Pmax.Text = strcat("Potencia Máxima: ", ...
                    num2str(app.VB.Value/(app.p.Value*app.RL.Value))," W");
            end
            IS = 0;
            if app.p.Value ~= 0 && app.Prx.Value ~= 0
                IS = app.p.Value*app.Prx.Value;
                app.Is.Text = strcat("Is: ",num2str(IS)," A");
            elseif app.Eficiencia ~= 0 && app.Prx.Value ~= 0 && app.F.Value ~= 0
                IS = CalculoIs(app.Eficiencia.Value,app.Prx.Value,app.F.Value);
                app.Is.Text = strcat("Is: ",num2str(IS)," A");
            end
            Pes = PES(IS,app.RL.Value);
            app.PES_Label.Text = strcat("PES: ",num2str(Pes), "W");
            Pnt = 0;
            Pns = 0;
            if app.T.Value ~= 0 && app.DF.Value ~= 0
                Pnt = PNT(app.T.Value,app.DF.Value);
                app.PNT_Label.Text = strcat("PNT: ",num2str(Pnt), "W");
            end
            if app.DF.Value ~= 0 && IS ~= 0 && app.RL.Value ~= 0 && app.Id.Value ~= 0
                Pns = PNS(app.DF.Value,IS,app.Id.Value,app.RL.Value);
                app.PNS_Label.Text = strcat("PNS: ",num2str(Pns), "W");
            end
            if Pnt ~= 0 && Pns ~= 0
                h = 6.62606957e-34; %Constante de plank;
                Sn = Pes/(Pnt+Pns);
                app.SN.Text = strcat("SN: ",num2str(Sn));
            end
            if app.Pe.Value ~= 0
                Sn = ((erfinv(1-app.Pe.Value*2))/0.354)^2;
                app.SN.Text = strcat("SN: ",num2str(Sn));
                if app.T.Value ~= 0 && app.DF.Value ~= 0
                    Pnt = PNT(app.T.Value,app.DF.Value);
                    app.PNT_Label.Text = strcat("PNT: ",num2str(Pnt), "W");
                end
                Pes = Pnt*Sn;
                IS = sqrt(Pes/app.RL.Value);
                app.Is.Text = strcat("Is: ",num2str(IS)," A");
                c = 3e8; %Velocidad de la luz
                h = 6.62606957e-34; %Constante de plank;
                e = 1.602176565e-19; % Constante de carga;
                app.p.Value = (app.Eficiencia.Value*e*app.Lambda.Value)/(h*c); %Responsividad
                app.Prx.Value = IS/app.p.Value; %Potencia óptica de entrada
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IngresodedatosPanel = uipanel(app.UIFigure);
            app.IngresodedatosPanel.Title = 'Ingreso de datos';
            app.Indicen1EditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.Indicen1EditFieldLabel.Text = 'Indice n1';
            app.n1 = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.Indicen2EditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.Indicen2EditFieldLabel.Text = 'Indice n2';
            app.n2 = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.NAEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.NAEditFieldLabel.Text = 'NA';
            app.NA = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.LambdamEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.LambdamEditFieldLabel.Text = 'Lambda (m)';
            app.Lambda = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.FrecuenciaHzEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.F = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.DiametromEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.DiametromEditFieldLabel.Text = 'Diametro (m)';
            app.D = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.LKmEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.LKmEditFieldLabel.Text = 'L (Km)';
            app.L = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.AlphadBKmEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.AlphadBKmEditFieldLabel.Text = 'Alpha (dB/Km)';
            app.Alpha = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.PtxWLabel = uilabel(app.IngresodedatosPanel);
            app.PtxWLabel.Text = '* Ptx (W)';
            app.Ptx = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.PrxWEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.PrxWEditFieldLabel.Text = 'Prx (W)';
            app.Prx = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.LconectordBLabel = uilabel(app.IngresodedatosPanel);
            app.LconectordBLabel.Text = 'Lconector (dB)';
            app.Lconector = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.LuniondBEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.LuniondBEditFieldLabel.Text = 'Lunion (dB)';
            app.Lunion = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.UnionesEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.UnionesEditFieldLabel.Text = '#Uniones';
            app.Uniones = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.T = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.TKEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.TKEditFieldLabel.Text = 'T (K)';
            app.DF = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.DFHzEditFieldLabel = uilabel(app.IngresodedatosPanel);
            app.DFHzEditFieldLabel.Text = 'DF (Hz)';
            app.RL = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.RLOhmsLabel = uilabel(app.IngresodedatosPanel);
            app.RLOhmsLabel.Text = '* RL (Ohms)';
            app.Eficiencia = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.nefcuantLabel = uilabel(app.IngresodedatosPanel);
            app.nefcuantLabel.Text = 'n (ef. cuant)';
            app.Pe = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.PeLabel = uilabel(app.IngresodedatosPanel);
            app.PeLabel.Text = 'Pe';
            app.Id = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.IdALabel = uilabel(app.IngresodedatosPanel);
            app.IdALabel.Text = 'Id (A)';
            app.p = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.pAWLabel = uilabel(app.IngresodedatosPanel);
            app.pAWLabel.Text = '* p (A/W)';
            app.VB = uieditfield(app.IngresodedatosPanel, 'numeric');
            app.VBVLabel = uilabel(app.IngresodedatosPanel);
            app.VBVLabel.Text = 'VB (V)';
            app.DesalineacionesPanel = uipanel(app.UIFigure);
            app.DesalineacionesPanel.Title = 'Desalineaciones';
            app.De1 = uieditfield(app.DesalineacionesPanel, 'numeric');
            app.De2 = uieditfield(app.DesalineacionesPanel, 'numeric');
            app.De3 = uieditfield(app.DesalineacionesPanel, 'numeric');
            app.TipoDropDownLabel = uilabel(app.DesalineacionesPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.Des1 = uidropdown(app.DesalineacionesPanel);
            app.Des1.Items = {'Lateral (m)', 'Angular (°)', 'Longitudinal (m)'};
            app.Des1.Value = 'Lateral (m)';
            app.TipoDropDown_2Label = uilabel(app.DesalineacionesPanel);
            app.TipoDropDown_2Label.Text = 'Tipo';
            app.Des2 = uidropdown(app.DesalineacionesPanel);
            app.Des2.Items = {'Lateral (m)', 'Angular (°)', 'Longitudinal (m)'};
            app.Des2.Value = 'Lateral (m)';
            app.TipoDropDown_3Label = uilabel(app.DesalineacionesPanel);
            app.TipoDropDown_3Label.Text = 'Tipo';
            app.Des3 = uidropdown(app.DesalineacionesPanel);
            app.Des3.Items = {'Lateral (m)', 'Angular (°)', 'Longitudinal (m)'};
            app.Des3.Value = 'Lateral (m)';
            app.Calcular = uibutton(app.UIFigure, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.Calcular.Text = 'Calcular';
            app.Lt = uilabel(app.UIFigure);
            app.Lt.Text = 'Pérdidas Totales: ';
            app.Circuito = uiimage(app.UIFigure);
            app.Circuito.ImageSource = 'Circuito.png';
            app.Pmax = uilabel(app.UIFigure);
            app.Pmax.Text = 'Potencia Máxima: ';
            app.Is = uilabel(app.UIFigure);
            app.Is.Text = 'Is: ';
            app.SN = uilabel(app.UIFigure);
            app.SN.Text = 'SN: ';
            app.CircuitodetectorPINLabel = uilabel(app.UIFigure);
            app.CircuitodetectorPINLabel.Text = 'Circuito detector PIN';
            app.PES_Label = uilabel(app.UIFigure);
            app.PES_Label.Text = 'PES: ';
            app.PNT_Label = uilabel(app.UIFigure);
            app.PNT_Label.Text = 'PNT: ';
            app.PNS_Label = uilabel(app.UIFigure);
            app.PNS_Label.Text = 'PNS: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PerdidasyFotodetectetor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end