classdef Lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab1UIFigure                    matlab.ui.Figure
        Label                           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Material1EditFieldLabel         matlab.ui.control.Label
        Material1EditField              matlab.ui.control.NumericEditField
        IndicederefraccinLabel          matlab.ui.control.Label
        Material2EditFieldLabel         matlab.ui.control.Label
        Material2EditField              matlab.ui.control.NumericEditField
        Material3EditFieldLabel         matlab.ui.control.Label
        Material3EditField              matlab.ui.control.NumericEditField
        Material4EditFieldLabel         matlab.ui.control.Label
        Material4EditField              matlab.ui.control.NumericEditField
        Material5EditFieldLabel         matlab.ui.control.Label
        Material5EditField              matlab.ui.control.NumericEditField
        EspesorenmmLabel                matlab.ui.control.Label
        Material1EditField_2Label       matlab.ui.control.Label
        Material1EditField_2            matlab.ui.control.NumericEditField
        Material2EditField_2Label       matlab.ui.control.Label
        Material2EditField_2            matlab.ui.control.NumericEditField
        Material3EditField_2Label       matlab.ui.control.Label
        Material3EditField_2            matlab.ui.control.NumericEditField
        Material4EditField_2Label       matlab.ui.control.Label
        Material4EditField_2            matlab.ui.control.NumericEditField
        Material5EditField_2Label       matlab.ui.control.Label
        Material5EditField_2            matlab.ui.control.NumericEditField
        OkButton_2                      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        Digitelacantidaddematerialesmax5EditField  matlab.ui.control.NumericEditField
        Digitelacantidaddematerialesmax5EditFieldLabel  matlab.ui.control.Label
        OkButton                        matlab.ui.control.Button
        IncidenciaAngulo                matlab.ui.control.NumericEditField
        DigiteelngulodeincidenciaengradosEditFieldLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        ResultadosLabel                 matlab.ui.control.Label
        RecorridototaldelhazdeluzLabel  matlab.ui.control.Label
        TotalHazmm                      matlab.ui.control.Label
        AngulosRefraccion               matlab.ui.control.Label
        Angulo1                         matlab.ui.control.Label
        Angulo2                         matlab.ui.control.Label
        Angulo3                         matlab.ui.control.Label
        Angulo4                         matlab.ui.control.Label
        Angulo5                         matlab.ui.control.Label
        AnguloCritico_2                 matlab.ui.control.Label
        AnguloCritico1                  matlab.ui.control.Label
        AnguloCritico2                  matlab.ui.control.Label
        AnguloCritico3                  matlab.ui.control.Label
        AnguloCritico4                  matlab.ui.control.Label
        AnguloCritico5                  matlab.ui.control.Label
        AnguloCritico6                  matlab.ui.control.Label
        ResultadosUIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function results = CalculoAnguloCritico(~,n1,n2)
            results = asin(n2/n1);
        end
        
        function results = CalculoAnguloR(~,n1,n2,theta)
            results = asin((n1*sin(theta))/n2);
        end
        
        function results = CalculoTrayectoria(~,angulos, espesores)
            j=1;
            trayectoriah = [0];
            trayectoriahn = [0];
            trayectoriav = [0];
            while j<=(length(angulos))
                b = (espesores(j)/cos(deg2rad(angulos(j))));
                o = espesores(j)*tan(deg2rad(angulos(j)));
                trayectoriah = [trayectoriah (b + trayectoriah(j))];
                trayectoriahn = [trayectoriahn (espesores(j)+trayectoriahn(j))];
                trayectoriav = [trayectoriav (o + trayectoriav(j))];
                j = j + 1;
            end
            trayectoria = [trayectoriahn;trayectoriav];
            %plot(trayectoria(1,:), trayectoria(2,:));
            results = trayectoria;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Panel_2.Visible = "off";
            app.Panel_3.Visible = "off";
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            Materiales = app.Digitelacantidaddematerialesmax5EditField.Value;
            if Materiales==1
                app.Material1EditField_2.Limits = [0.001 Inf];
                app.Material2EditField.Visible = "off";
                app.Material3EditField.Visible = "off";
                app.Material4EditField.Visible = "off";
                app.Material5EditField.Visible = "off";
                app.Material2EditField_2.Visible = "off";
                app.Material3EditField_2.Visible = "off";
                app.Material4EditField_2.Visible = "off";
                app.Material5EditField_2.Visible = "off";
            elseif Materiales==2
                app.Material1EditField_2.Limits = [0.001 Inf];
                app.Material2EditField_2.Limits = [0.001 Inf];
                app.Material3EditField.Visible = "off";
                app.Material4EditField.Visible = "off";
                app.Material5EditField.Visible = "off";
                app.Material3EditField_2.Visible = "off";
                app.Material4EditField_2.Visible = "off";
                app.Material5EditField_2.Visible = "off";
            elseif Materiales==3
                app.Material1EditField_2.Limits = [0.001 Inf];
                app.Material2EditField_2.Limits = [0.001 Inf];
                app.Material3EditField_2.Limits = [0.001 Inf];
                app.Material4EditField.Visible = "off";
                app.Material5EditField.Visible = "off";
                app.Material4EditField_2.Visible = "off";
                app.Material5EditField_2.Visible = "off";
            elseif Materiales==4
                app.Material1EditField_2.Limits = [0.001 Inf];
                app.Material2EditField_2.Limits = [0.001 Inf];
                app.Material3EditField_2.Limits = [0.001 Inf];
                app.Material4EditField_2.Limits = [0.001 Inf];
                app.Material5EditField.Visible = "off";
                app.Material5EditField_2.Visible = "off";
            else
                app.Material1EditField_2.Limits = [0.001 Inf];
                app.Material2EditField_2.Limits = [0.001 Inf];
                app.Material3EditField_2.Limits = [0.001 Inf];
                app.Material4EditField_2.Limits = [0.001 Inf];
                app.Material5EditField_2.Limits = [0.001 Inf];
            end
            app.Panel.Visible = "off";
            app.Panel_2.Visible = "on";
        end

        % Button pushed function: OkButton_2
        function OkButton_2Pushed(app, event)
            app.Panel_2.Visible = "off";
            app.Panel_3.Visible = "on";
            Materiales = app.Digitelacantidaddematerialesmax5EditField.Value;
            if Materiales==1
                AnguloR1 = rad2deg(CalculoAnguloR(app,...
                    1,app.Material1EditField.Value,...
                    deg2rad(app.IncidenciaAngulo.Value)));
                app.Angulo1.Text = "θ1=" + num2str(AnguloR1);
                app.AnguloCritico1.Text = "θc1="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    1,app.Material1EditField.Value)));
                angulos = (AnguloR1);
            elseif Materiales==2
                AnguloR1 = rad2deg(CalculoAnguloR(app,...
                    1,app.Material1EditField.Value,...
                    deg2rad(app.IncidenciaAngulo.Value)));
                app.Angulo1.Text = "θ1=" + num2str(AnguloR1);
                AnguloR2 = rad2deg(CalculoAnguloR(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value,...
                    deg2rad(AnguloR1)));
                app.Angulo2.Text = "θ2=" + num2str(AnguloR2);
                app.AnguloCritico1.Text = "θc1="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    1,app.Material1EditField.Value)));
                app.AnguloCritico2.Text = "θc2="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value)));
                angulos = [AnguloR1 AnguloR2];
            elseif Materiales==3
                AnguloR1 = rad2deg(CalculoAnguloR(app,...
                    1,app.Material1EditField.Value,...
                    deg2rad(app.IncidenciaAngulo.Value)));
                app.Angulo1.Text = "θ1=" + num2str(AnguloR1);
                AnguloR2 = rad2deg(CalculoAnguloR(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value,...
                    deg2rad(AnguloR1)));
                app.Angulo2.Text = "θ2=" + num2str(AnguloR2);
                AnguloR3 = rad2deg(CalculoAnguloR(app,...
                    app.Material2EditField.Value,app.Material3EditField.Value,...
                    deg2rad(AnguloR2)));
                app.Angulo3.Text = "θ3=" + num2str(AnguloR3);
                app.AnguloCritico1.Text = "θc1="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    1,app.Material1EditField.Value)));
                app.AnguloCritico2.Text = "θc2="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value)));
                app.AnguloCritico3.Text = "θc3="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material2EditField.Value,app.Material3EditField.Value)));
                angulos = [AnguloR1 AnguloR2 AnguloR3];
            elseif Materiales==4
                AnguloR1 = rad2deg(CalculoAnguloR(app,...
                    1,app.Material1EditField.Value,...
                    deg2rad(app.IncidenciaAngulo.Value)));
                app.Angulo1.Text = "θ1=" + num2str(AnguloR1);
                AnguloR2 = rad2deg(CalculoAnguloR(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value,...
                    deg2rad(AnguloR1)));
                app.Angulo2.Text = "θ2=" + num2str(AnguloR2);
                AnguloR3 = rad2deg(CalculoAnguloR(app,...
                    app.Material2EditField.Value,app.Material3EditField.Value,...
                    deg2rad(AnguloR2)));
                app.Angulo3.Text = "θ3=" + num2str(AnguloR3);
                AnguloR4 = rad2deg(CalculoAnguloR(app,...
                    app.Material3EditField.Value,app.Material4EditField.Value,...
                    deg2rad(AnguloR3)));
                app.Angulo4.Text = "θ4=" + num2str(AnguloR4);
                app.AnguloCritico1.Text = "θc1="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    1,app.Material1EditField.Value)));
                app.AnguloCritico2.Text = "θc2="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value)));
                app.AnguloCritico3.Text = "θc3="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material2EditField.Value,app.Material3EditField.Value)));
                app.AnguloCritico4.Text = "θc4="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material3EditField.Value,app.Material4EditField.Value)));
                angulos = [AnguloR1 AnguloR2 AnguloR3 AnguloR4];
            else
                AnguloR1 = rad2deg(CalculoAnguloR(app,...
                    1,app.Material1EditField.Value,...
                    deg2rad(app.IncidenciaAngulo.Value)));
                app.Angulo1.Text = "θ1=" + num2str(AnguloR1);
                AnguloR2 = rad2deg(CalculoAnguloR(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value,...
                    deg2rad(AnguloR1)));
                app.Angulo2.Text = "θ2=" + num2str(AnguloR2);
                AnguloR3 = rad2deg(CalculoAnguloR(app,...
                    app.Material2EditField.Value,app.Material3EditField.Value,...
                    deg2rad(AnguloR2)));
                app.Angulo3.Text = "θ3=" + num2str(AnguloR3);
                AnguloR4 = rad2deg(CalculoAnguloR(app,...
                    app.Material3EditField.Value,app.Material4EditField.Value,...
                    deg2rad(AnguloR3)));
                app.Angulo4.Text = "θ4=" + num2str(AnguloR4);
                AnguloR5 = rad2deg(CalculoAnguloR(app,...
                    app.Material4EditField.Value,app.Material5EditField.Value,...
                    deg2rad(AnguloR4)));
                app.Angulo5.Text = "θ5=" + num2str(AnguloR5);
                app.AnguloCritico1.Text = "θc1="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    1,app.Material1EditField.Value)));
                app.AnguloCritico2.Text = "θc2="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material1EditField.Value,app.Material2EditField.Value)));
                app.AnguloCritico3.Text = "θc3="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material2EditField.Value,app.Material3EditField.Value)));
                app.AnguloCritico4.Text = "θc4="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material3EditField.Value,app.Material4EditField.Value)));
                app.AnguloCritico5.Text = "θc5="+...
                    num2str(rad2deg(CalculoAnguloCritico(app,...
                    app.Material4EditField.Value,app.Material5EditField.Value)));
                angulos = [AnguloR1 AnguloR2 AnguloR3 AnguloR4 AnguloR5];
            end
            espesores = [];
            suma=app.Material1EditField_2.Value;
            espesores = [espesores app.Material1EditField_2.Value];
            xline(app.ResultadosUIAxes,suma);
            xline(app.ResultadosUIAxes,suma);
            suma = suma + app.Material2EditField_2.Value;
            espesores = [espesores app.Material2EditField_2.Value];
            xline(app.ResultadosUIAxes,suma);
            suma = suma + app.Material3EditField_2.Value;
            espesores = [espesores app.Material3EditField_2.Value];
            xline(app.ResultadosUIAxes,suma);
            suma = suma + app.Material4EditField_2.Value;
            espesores = [espesores app.Material4EditField_2.Value];
            xline(app.ResultadosUIAxes,suma);
            suma = suma + app.Material5EditField_2.Value;
            espesores = [espesores app.Material5EditField_2.Value];
            xline(app.ResultadosUIAxes,suma);
            xlim(app.ResultadosUIAxes,[-0.002 suma+0.002]);
            xline(app.ResultadosUIAxes,0);
            yline(app.ResultadosUIAxes,0);
            xlabel(app.ResultadosUIAxes,"mm");
            

% Espesores            
            trayectoria = app.CalculoTrayectoria(angulos, espesores);
            trayectoriah = trayectoria(1, :);
            trayectoriav = trayectoria(2, :);
            app.TotalHazmm.Text = num2str(sum(trayectoriah))+" mm";
            hold(app.ResultadosUIAxes, "on");
            plot(app.ResultadosUIAxes,trayectoriah, trayectoriav);
            app.ResultadosUIAxes.set('Color','#85F548')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab1UIFigure = uifigure('Visible', 'off');
            app.Lab1UIFigure.Name = 'Lab1';
            app.Lab1UIFigure.Icon = 'BeamLight.gif';
            app.Label = uilabel(app.Lab1UIFigure);
            app.Label.Text = {'Este programa ayuda a graficar el paso de un haz de luz a través de varios medios,'; 'así como calcular el ángulo de refracción para cada medio.'};
            app.Panel_2 = uipanel(app.Lab1UIFigure);
            app.Material1EditFieldLabel = uilabel(app.Panel_2);
            app.Material1EditFieldLabel.Text = 'Material 1';
            app.Material1EditField = uieditfield(app.Panel_2, 'numeric');
            app.Material1EditField.Limits = [1 Inf];
            app.Material1EditField.Value = 1;
            app.IndicederefraccinLabel = uilabel(app.Panel_2);
            app.IndicederefraccinLabel.Text = 'Indice de refracción';
            app.Material2EditFieldLabel = uilabel(app.Panel_2);
            app.Material2EditFieldLabel.Text = 'Material 2';
            app.Material2EditField = uieditfield(app.Panel_2, 'numeric');
            app.Material2EditField.Limits = [1 Inf];
            app.Material2EditField.Value = 1;
            app.Material3EditFieldLabel = uilabel(app.Panel_2);
            app.Material3EditFieldLabel.Text = 'Material 3';
            app.Material3EditField = uieditfield(app.Panel_2, 'numeric');
            app.Material3EditField.Limits = [1 Inf];
            app.Material3EditField.Value = 1;
            app.Material4EditFieldLabel = uilabel(app.Panel_2);
            app.Material4EditFieldLabel.Text = 'Material 4';
            app.Material4EditField = uieditfield(app.Panel_2, 'numeric');
            app.Material4EditField.Limits = [1 Inf];
            app.Material4EditField.Value = 1;
            app.Material5EditFieldLabel = uilabel(app.Panel_2);
            app.Material5EditFieldLabel.Text = 'Material 5';
            app.Material5EditField = uieditfield(app.Panel_2, 'numeric');
            app.Material5EditField.Limits = [1 Inf];
            app.Material5EditField.Value = 1;
            app.EspesorenmmLabel = uilabel(app.Panel_2);
            app.EspesorenmmLabel.Text = 'Espesor en mm';
            app.Material1EditField_2Label = uilabel(app.Panel_2);
            app.Material1EditField_2Label.Text = 'Material 1';
            app.Material1EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Material1EditField_2.Limits = [0 Inf];
            app.Material2EditField_2Label = uilabel(app.Panel_2);
            app.Material2EditField_2Label.Text = 'Material 2';
            app.Material2EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Material2EditField_2.Limits = [0 Inf];
            app.Material3EditField_2Label = uilabel(app.Panel_2);
            app.Material3EditField_2Label.Text = 'Material 3';
            app.Material3EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Material3EditField_2.Limits = [0 Inf];
            app.Material4EditField_2Label = uilabel(app.Panel_2);
            app.Material4EditField_2Label.Text = 'Material 4';
            app.Material4EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Material4EditField_2.Limits = [0 Inf];
            app.Material5EditField_2Label = uilabel(app.Panel_2);
            app.Material5EditField_2Label.Text = 'Material 5';
            app.Material5EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Material5EditField_2.Limits = [0 Inf];
            app.OkButton_2 = uibutton(app.Panel_2, 'push');
            app.OkButton_2.ButtonPushedFcn = createCallbackFcn(app, @OkButton_2Pushed, true);
            app.OkButton_2.Text = 'Ok';
            app.Panel = uipanel(app.Lab1UIFigure);
            app.Digitelacantidaddematerialesmax5EditField = uieditfield(app.Panel, 'numeric');
            app.Digitelacantidaddematerialesmax5EditField.Limits = [1 5];
            app.Digitelacantidaddematerialesmax5EditField.Value = 1;
            app.Digitelacantidaddematerialesmax5EditFieldLabel = uilabel(app.Panel);
            app.Digitelacantidaddematerialesmax5EditFieldLabel.Text = 'Digite la cantidad de materiales (max. 5)';
            app.OkButton = uibutton(app.Panel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.IncidenciaAngulo = uieditfield(app.Panel, 'numeric');
            app.DigiteelngulodeincidenciaengradosEditFieldLabel = uilabel(app.Panel);
            app.DigiteelngulodeincidenciaengradosEditFieldLabel.Text = 'Digite el ángulo de incidencia (en grados)';
            app.Panel_3 = uipanel(app.Lab1UIFigure);
            app.ResultadosLabel = uilabel(app.Panel_3);
            app.ResultadosLabel.Text = 'Resultados';
            app.RecorridototaldelhazdeluzLabel = uilabel(app.Panel_3);
            app.RecorridototaldelhazdeluzLabel.Text = 'Recorrido total del haz de luz';
            app.TotalHazmm = uilabel(app.Panel_3);
            app.TotalHazmm.Text = '';
            app.AngulosRefraccion = uilabel(app.Panel_3);
            app.AngulosRefraccion.Text = 'Ángulos de refracción °';
            app.Angulo1 = uilabel(app.Panel_3);
            app.Angulo1.Text = '';
            app.Angulo2 = uilabel(app.Panel_3);
            app.Angulo2.Text = '';
            app.Angulo3 = uilabel(app.Panel_3);
            app.Angulo3.Text = '';
            app.Angulo4 = uilabel(app.Panel_3);
            app.Angulo4.Text = '';
            app.Angulo5 = uilabel(app.Panel_3);
            app.Angulo5.Text = '';
            app.AnguloCritico_2 = uilabel(app.Panel_3);
            app.AnguloCritico_2.Text = 'Ángulos críticos °';
            app.AnguloCritico1 = uilabel(app.Panel_3);
            app.AnguloCritico1.Text = '';
            app.AnguloCritico2 = uilabel(app.Panel_3);
            app.AnguloCritico2.Text = '';
            app.AnguloCritico3 = uilabel(app.Panel_3);
            app.AnguloCritico3.Text = '';
            app.AnguloCritico4 = uilabel(app.Panel_3);
            app.AnguloCritico4.Text = '';
            app.AnguloCritico5 = uilabel(app.Panel_3);
            app.AnguloCritico5.Text = '';
            app.AnguloCritico6 = uilabel(app.Panel_3);
            app.AnguloCritico6.Text = '';
            app.ResultadosUIAxes = uiaxes(app.Panel_3);
            title(app.ResultadosUIAxes, 'Gráfica')
            xlabel(app.ResultadosUIAxes, 'X')
            ylabel(app.ResultadosUIAxes, 'Y')
            zlabel(app.ResultadosUIAxes, 'Z')
            % Show the figure after all components are created
            app.Lab1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab1UIFigure)
        end
    end
end